classdef LiCongYYDS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup2              matlab.ui.container.TabGroup
        observeV               matlab.ui.container.Tab
        Label                  matlab.ui.control.Label
        Lamp_card              matlab.ui.control.Lamp
        Switch_card            matlab.ui.control.Switch
        Panel_channel1         matlab.ui.container.Panel
        rpmLabel               matlab.ui.control.Label
        Label_3                matlab.ui.control.Label
        V_channel              matlab.ui.control.NumericEditField
        Button_isFreze         matlab.ui.control.StateButton
        Button_selectAlg       matlab.ui.container.ButtonGroup
        MButton                matlab.ui.control.RadioButton
        TButton                matlab.ui.control.RadioButton
        Label_5                matlab.ui.control.Label
        selectChannel          matlab.ui.control.DropDown
        Button_getV            matlab.ui.control.StateButton
        Panel                  matlab.ui.container.Panel
        Label_4                matlab.ui.control.Label
        samplingRate           matlab.ui.control.NumericEditField
        EditField_2Label_2     matlab.ui.control.Label
        samplingExtent         matlab.ui.control.NumericEditField
        Tab_3                  matlab.ui.container.Tab
        UIAxes_sub             matlab.ui.control.UIAxes
        UIAxes_main            matlab.ui.control.UIAxes
        TerminalTextAreaLabel  matlab.ui.control.Label
        TerminalTextArea       matlab.ui.control.TextArea
    end


    properties (Access = private)
        Fs % 采样频率
        N %采样长度
        T %时间长度
        channelNow %当前通道号码
        signalChannel %采集卡信号

        X %横坐标
        cardState = 0;
        
        xLimit %横坐标范围
        
        cardData % Description
        algs %选中的测速算法 'M' stands for M; 'T' stands for T
        %% 辅助参数
        terminalText
        isFreze % 是否冻结显示
        
    end

    methods (Access = private)
    
        function show_channel_signal(app)
            get_channel_signal(app);
            plot_channel_signal(app);
        end


        function get_channel_signal(app)
            refreshT(app)
            app.cardData=USBDAQ_readtwoCH(app.N,app.Fs,app.channelNow);
            app.signalChannel.Y=app.CardData(:,1);
        end
        
        function plot_channel_signal(app)
            zoom = 1;
            xUpper = round(zoom*size(app.X,2));
            plot(app.UIAxes_main,app.X(1:xUpper),app.signalChannel.Y(1:xUpper));
            drawnow;
        end
        
        %% 数据分析
        function f = TDZCD(app,y)
        %Time domain zero crossing detection时域过零检测算法
            y_prime = y - mean(y);
            switch(app.algs)
                case 'M'
                    counter =0;
                    for i=1:app.N-1
                        if y_prime(i)*y_prime(i+1)< 0
                            counter = counter+1;
                        end
                    end
                    f = counter/(2*app.T);
                case 'T'
                    isOdd = 2;
                    last_node = 0;
                    counter = 0;
                    total_gap = 0;
                    for i = 1:app.N-1
                        if y_prime(i)*y_prime(i+1)< 0
                            if isOdd
                                if isOdd == 2
                                    last_node = i;
                                end
                                isOdd = isOdd -1;
                            else
                                gap = i - last_node;
                                counter = counter + 1;
                                total_gap = total_gap + gap;
                                isOdd = 2;
                            end
                        end
                    end
                    if counter == 0
                        f = 0;
                    else
                        f = 1 / (total_gap/counter*1/app.Fs);
                    end
            end
        end
        
        %%辅助函数
        function refreshT(app)
            app.T = app.N/app.Fs;
        end
        
        function add_terminal_text(app,newLine)
            line_num = size(app.terminalText);
            maxLine = 3;
            if line_num < maxLine
                app.terminalText{end + 1} = newLine;
            else
                for i = 1:maxLine-1
                    app.terminalText{i} = app.terminalText{i+1};
                end
                    app.terminalText{maxLine} = newLine;
            end
            app.TerminalTextArea.Value = app.terminalText;
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.samplingRate.Value = 5120;
            app.Fs = app.samplingRate.Value;
            app.samplingExtent.Value = 1024;
            app.N = app.samplingExtent.Value;
            refreshT(app);
            app.X = linspace(0,app.T,app.N);
            app.signalChannel.Y = zeros(1,app.N);
            
            app.Switch_card.ItemsData = {0,1}; %赋采集卡开关的初值
            app.Lamp_card.Color = 'red';
            
            app.algs = 'M';
            app.terminalText = {'请开始你的表演'};
            app.TerminalTextArea.Value = app.terminalText;
            
            %% 通道选择按钮的初始化
            app.selectChannel.Items = {'通道1','通道5'};
            app.selectChannel.ItemsData = {1,5};
            plot_channel_signal(app);
            app.V_channel.Value = TDZCD(app,app.signalChannel.Y);
        end

        % Value changed function: Button_getV
        function Button_getVValueChanged(app, event)
            state = app.Button_getV.Value;
            if app.cardState == 0 && state == 1
                add_terminal_text(app,'请打开采集卡');
                app.Button_getV.Value = 0;
                return
            end
            if state ==1
                add_terminal_text(app,'开始测速');
            end
            while state
               app.V_channel.Value = TDZCD(app,app.signalChannel.Y);
            end
            if state == 0
                app.V_channel.Value = 0;
                add_terminal_text(app,'测速结束');
            end
        end

        % Value changed function: Button_isFreze
        function Button_isFrezeValueChanged(app, event)
            app.isFreze = app.Button_isFreze.Value;
        end

        % Selection changed function: Button_selectAlg
        function Button_selectAlgSelectionChanged(app, event)
             if app.cardState == 1
                 add_terminal_text(app,'请先关闭采集卡');
                 switch(app.algs)
                     case 'M'
                         app.MButton.Value = true;
                         app.TButton.Value = false;
                     case 'T'
                         app.MButton.Value = false;
                         app.TButton.Value = true;
                 end       
             else
                 if app.MButton.Value == true
                     app.algs = 'M';
                     add_terminal_text(app,'M算法测速');
                 end
                 if app.TButton.Value == true
                     app.algs = 'T';
                     add_terminal_text(app,'T算法测速');
                 end
             end 
        end

        % Value changed function: Switch_card
        function Switch_cardValueChanged(app, event)
            value = app.Switch_card.Value;
            
            if (value == 0)
                app.Lamp_card.Color = 'red';
                app.cardState = 0;
                USBDAQ_closecard;
                add_terminal_text(app,'采集卡已关闭');
            else
                app.cardState=USBDAQ_opencard;
                if app.cardState==1
                    disp('采集卡开启成功');
                    add_terminal_text(app,'采集卡开启成功');
                    app.Lamp_card.Color = 'green';
                else
                    disp('采集卡开启失败');
                    add_terminal_text(app,'采集卡开启失败');
                    app.Switch_card.Value = 0;
                    
                end
                %循环控制，以达到实时更新
                while(app.cardState)
                    show_channel_signal(app);
                    pause(0.2);
                end
            end
        end

        % Value changed function: samplingExtent
        function samplingExtentValueChanged(app, event)
            
            if app.cardState == 1
                app.samplingExtent.Value = app.N;
                add_terminal_text(app,'请先关闭采集卡')
            else
                app.N = app.samplingExtent.Value;
            end
        end

        % Value changed function: samplingRate
        function samplingRateValueChanged(app, event)
            if app.cardState == 1
                app.samplingRate.Value = app.Fs;
                add_terminal_text(app,'请先关闭采集卡')
            else
                app.Fs = app.samplingRate.Value;
            end
        end

        % Value changed function: selectChannel
        function selectChannelValueChanged(app, event)
            if app.cardState == 1
                app.selectChannel.Value = app.channelNow;
                add_terminal_text(app,'请先关闭采集卡');
            else
                app.channelNow = app.selectChannel.Value;
                add_terminal_text(app,['你已经切换到了' num2str(app.channelNow) '通道']);
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.observeV = uitab(app.TabGroup2);
            app.observeV.Title = '转子测速';
            app.Label = uilabel(app.observeV);
            app.Label.Text = '采集卡状态';
            app.Lamp_card = uilamp(app.observeV);
            app.Switch_card = uiswitch(app.observeV, 'slider');
            app.Switch_card.ValueChangedFcn = createCallbackFcn(app, @Switch_cardValueChanged, true);
            app.Panel_channel1 = uipanel(app.observeV);
            app.Panel_channel1.Title = '测速';
            app.rpmLabel = uilabel(app.Panel_channel1);
            app.rpmLabel.Text = 'rpm';
            app.Label_3 = uilabel(app.Panel_channel1);
            app.Label_3.Text = '转速';
            app.V_channel = uieditfield(app.Panel_channel1, 'numeric');
            app.V_channel.Editable = 'off';
            app.Button_isFreze = uibutton(app.Panel_channel1, 'state');
            app.Button_isFreze.ValueChangedFcn = createCallbackFcn(app, @Button_isFrezeValueChanged, true);
            app.Button_isFreze.Text = '冻结显示';
            app.Button_selectAlg = uibuttongroup(app.Panel_channel1);
            app.Button_selectAlg.SelectionChangedFcn = createCallbackFcn(app, @Button_selectAlgSelectionChanged, true);
            app.Button_selectAlg.Title = '选择测速算法';
            app.MButton = uiradiobutton(app.Button_selectAlg);
            app.MButton.Text = 'M算法（高速）';
            app.MButton.Value = true;
            app.TButton = uiradiobutton(app.Button_selectAlg);
            app.TButton.Text = 'T算法（低速）';
            app.Label_5 = uilabel(app.Panel_channel1);
            app.Label_5.Text = '选择频通';
            app.selectChannel = uidropdown(app.Panel_channel1);
            app.selectChannel.Items = {'1', '5'};
            app.selectChannel.ValueChangedFcn = createCallbackFcn(app, @selectChannelValueChanged, true);
            app.selectChannel.Value = '5';
            app.Button_getV = uibutton(app.Panel_channel1, 'state');
            app.Button_getV.ValueChangedFcn = createCallbackFcn(app, @Button_getVValueChanged, true);
            app.Button_getV.Text = '测速';
            app.Panel = uipanel(app.observeV);
            app.Panel.Title = '采样设置';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '采样频率';
            app.samplingRate = uieditfield(app.Panel, 'numeric');
            app.samplingRate.ValueChangedFcn = createCallbackFcn(app, @samplingRateValueChanged, true);
            app.samplingRate.Limits = [0 Inf];
            app.samplingRate.ValueDisplayFormat = '%.0f';
            app.EditField_2Label_2 = uilabel(app.Panel);
            app.EditField_2Label_2.Text = '采样长度';
            app.samplingExtent = uieditfield(app.Panel, 'numeric');
            app.samplingExtent.ValueChangedFcn = createCallbackFcn(app, @samplingExtentValueChanged, true);
            app.samplingExtent.Limits = [0 Inf];
            app.samplingExtent.ValueDisplayFormat = '%.0f';
            app.Tab_3 = uitab(app.TabGroup2);
            app.Tab_3.Title = '仿真';
            app.UIAxes_sub = uiaxes(app.UIFigure);
            title(app.UIAxes_sub, 'Title')
            xlabel(app.UIAxes_sub, 't')
            ylabel(app.UIAxes_sub, 'A')
            app.UIAxes_main = uiaxes(app.UIFigure);
            title(app.UIAxes_main, 'Title')
            xlabel(app.UIAxes_main, 't')
            ylabel(app.UIAxes_main, 'A')
            app.TerminalTextAreaLabel = uilabel(app.UIFigure);
            app.TerminalTextAreaLabel.Text = 'Terminal';
            app.TerminalTextArea = uitextarea(app.UIFigure);
            app.TerminalTextArea.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = LiCongYYDS

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end